classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        SurfButton         matlab.ui.control.Button
        MeshButton         matlab.ui.control.Button
        ContoursButton     matlab.ui.control.Button
        DataDropDownLabel  matlab.ui.control.Label
        DataDropDown       matlab.ui.control.DropDown
        UIAxes             matlab.ui.control.UIAxes
    end

    properties (Access = private)
        peak
        membran
        sinc
        curr
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.peak = peaks(35);
            app.membran = membrane;
            [x,y] = meshgrid(-8:.5:8);
            r = sqrt(x.^2+y.^2) + eps;
            sincs = sin(r)./r;
            app.sinc = sincs;
            app.curr = app.peak;
            surf(app.UIAxes,app.curr);
        end

        % Button pushed function: SurfButton
        function SurfButtonPushed(app, event)
           surf(app.UIAxes, app.curr)
        end

        % Button pushed function: MeshButton
        function MeshButtonPushed(app, event)
            mesh(app.UIAxes, app.curr);
        end

        % Button pushed function: ContoursButton
        function ContoursButtonPushed(app, event)
            contour(app.UIAxes, app.curr);
        end

        % Value changed function: DataDropDown
        function DataDropDownValueChanged(app, event)
            value = app.DataDropDown.Value;
            switch value
                case 'peaks'
                    app.curr = app.peak;
                case 'membrane'
                    app.curr = app.membran;
                case 'sinc'
                    app.curr = app.sinc;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SurfButton = uibutton(app.UIFigure, 'push');
            app.SurfButton.ButtonPushedFcn = createCallbackFcn(app, @SurfButtonPushed, true);
            app.SurfButton.Text = 'Surf';
            app.MeshButton = uibutton(app.UIFigure, 'push');
            app.MeshButton.ButtonPushedFcn = createCallbackFcn(app, @MeshButtonPushed, true);
            app.MeshButton.Text = 'Mesh';
            app.ContoursButton = uibutton(app.UIFigure, 'push');
            app.ContoursButton.ButtonPushedFcn = createCallbackFcn(app, @ContoursButtonPushed, true);
            app.ContoursButton.Text = 'Contours';
            app.DataDropDownLabel = uilabel(app.UIFigure);
            app.DataDropDownLabel.Text = 'Data';
            app.DataDropDown = uidropdown(app.UIFigure);
            app.DataDropDown.Items = {'peaks', 'membrane', 'sinc'};
            app.DataDropDown.ValueChangedFcn = createCallbackFcn(app, @DataDropDownValueChanged, true);
            app.DataDropDown.Value = 'peaks';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end